classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Principal                       matlab.ui.container.Tab
        PortaCOMSensorEditFieldLabel    matlab.ui.control.Label
        PortaCOMSensorEditField         matlab.ui.control.NumericEditField
        BuscarSensorButton              matlab.ui.control.Button
        DistanciaentresensordealturaesondaemYmmEditField  matlab.ui.control.NumericEditField
        DistanciaentresensordealturaesondaemYmmEditFieldLabel  matlab.ui.control.Label
        DistanciaentresensordealturaesondaemXmmEditField  matlab.ui.control.NumericEditField
        DistanciaentresensordealturaesondaemXmmEditFieldLabel  matlab.ui.control.Label
        TamanhodasondaEditField         matlab.ui.control.NumericEditField
        TamanhodasondaEditFieldLabel    matlab.ui.control.Label
        MostarmapadealturasSwitch       matlab.ui.control.Switch
        MostarmapadealturasSwitchLabel  matlab.ui.control.Label
        UsarescalaautomaticaSwitch      matlab.ui.control.Switch
        UsarescalaautomaticaSwitchLabel  matlab.ui.control.Label
        ParmetrosdaPlacaLabel           matlab.ui.control.Label
        PortaCOMdoTINYEditFieldLabel    matlab.ui.control.Label
        MODODEOPERAODropDownLabel       matlab.ui.control.Label
        FreqFinalEditFieldLabel         matlab.ui.control.Label
        NumerodefrequenciasSpinnerLabel  matlab.ui.control.Label
        FreqInicialEditFieldLabel       matlab.ui.control.Label
        TempoEstimadoEditFieldLabel     matlab.ui.control.Label
        Freq1MHzEditFieldLabel          matlab.ui.control.Label
        Freq4MHzEditFieldLabel          matlab.ui.control.Label
        Freq3MHzEditFieldLabel          matlab.ui.control.Label
        Freq2MHzEditFieldLabel          matlab.ui.control.Label
        ComprimentoXmmEditFieldLabel    matlab.ui.control.Label
        LarguraYmmEditFieldLabel        matlab.ui.control.Label
        AlturadomaiorcomponentemmEditField_2Label  matlab.ui.control.Label
        ResoluoPassommEditFieldLabel    matlab.ui.control.Label
        NomedoArquivoEditFieldLabel     matlab.ui.control.Label
        STATUSTextAreaLabel             matlab.ui.control.Label
        PortaCOMdoSCANNEREditFieldLabel  matlab.ui.control.Label
        DistnciaasermantidadaplacammEditFieldLabel  matlab.ui.control.Label
        ParmetrosdaMedioLabel           matlab.ui.control.Label
        moverButton                     matlab.ui.control.Button
        STATUSTextArea                  matlab.ui.control.TextArea
        medealturaButton                matlab.ui.control.Button
        medecampoButton                 matlab.ui.control.Button
        IniciarButton                   matlab.ui.control.Button
        NomedoArquivoEditField          matlab.ui.control.EditField
        DistnciaasermantidadaplacammEditField  matlab.ui.control.NumericEditField
        ResoluoPassommEditField         matlab.ui.control.NumericEditField
        AlturadomaiorcomponentemmEditField_2  matlab.ui.control.NumericEditField
        LarguraYmmEditField             matlab.ui.control.NumericEditField
        ComprimentoXmmEditField         matlab.ui.control.NumericEditField
        TempoEstimadoEditField          matlab.ui.control.EditField
        DropDownFreq4                   matlab.ui.control.DropDown
        Freq4MHzEditField               matlab.ui.control.NumericEditField
        DropDownFreq3                   matlab.ui.control.DropDown
        Freq3MHzEditField               matlab.ui.control.NumericEditField
        DropDownFreq2                   matlab.ui.control.DropDown
        Freq2MHzEditField               matlab.ui.control.NumericEditField
        DropDownFreq1                   matlab.ui.control.DropDown
        Freq1MHzEditField               matlab.ui.control.NumericEditField
        NumerodefrequenciasSpinner      matlab.ui.control.Spinner
        RBWkHzEditFieldLabel            matlab.ui.control.Label
        DropDownRBW                     matlab.ui.control.DropDown
        DropDownFreqFinal               matlab.ui.control.DropDown
        FreqFinalEditField              matlab.ui.control.EditField
        DropDownFreqInicial             matlab.ui.control.DropDown
        FreqInicialEditField            matlab.ui.control.EditField
        MODODEOPERAODropDown            matlab.ui.control.DropDown
        PortaCOMdoSCANNEREditField      matlab.ui.control.NumericEditField
        PortaCOMdoTINYEditField         matlab.ui.control.NumericEditField
        BuscarSCANNERButton             matlab.ui.control.Button
        BuscarTINYButton                matlab.ui.control.Button
        CAMPOSTab                       matlab.ui.container.Tab
        FrequenciaSpinnerLabel          matlab.ui.control.Label
        FrequenciaSpinner               matlab.ui.control.Spinner
        EditField                       matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        MXIMOTab                        matlab.ui.container.Tab
        UIAxesCampo                     matlab.ui.control.UIAxes
        UIAxesFreq                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        %==================== Comunicação Serial===========================
        tiny
        scanner
        sensor
        %====================Variavéis Dimensões===========================
        mostrar_mapa_alt = 's'
        X
        Y
        Altura_seguranca
        Res %Resolução
        numero_de_pontos
        desloc_max_Y = 110
        desloc_max_X = 40
        Altura_seguranca_max = 110
        Tolerancia_altura_seg = 2
        Dist_sonda
        tam_sonda = 40
        Dist_pl
        Desloc_sensor_X = 35
        Desloc_sensor_Y = 55

        %====================Variaveis Completa ===========================
        modo_operacao
        erro_leitura
        %====================Varredura Completa============================
        freq_inicial
        freq_final
        prefixo_freq_inicial
        base10_freq_inicial
        prefixo_freq_final
        base10_freq_final
        rbw = 850
        freqs
        amps
        amp_max_ponto
        soma_amps = zeros(1,290)
        VerticalLine %Linha vertical do cursor de frequencias
        cursor_freq %Valor do cursor de frequencias
        n_pontos_coluna_x
        n_pontos_linha_y
        todas_amplitudes
        todas_frequencias
        amp_max_freq
        %====================Frequências Específicas=======================
        prefixo
        
        nome
        lista_freq
        escala_graficos
        num_freq


    end

    methods (Access = private)

        function mover_XYZ(app,x,y,z) %okk
               buf = sprintf("MX%03d.0Y%03d.0Z%03d.0",x,y,z);
               writeline(app.scanner, buf);
    
               a = 1;

               while(a)
                   resposta = readline(app.scanner);
                   if(isa(resposta, "string"))
                       a = 0;
                   end
               end

               % s = strcat("OK mov ", buf);
               % app.STATUSTextArea.Value = s;
        end

        function [amp]=mede_campo(app,freq_count)

                teste = 1;
                while(teste == 1) 

                    frequencia = app.lista_freq(freq_count);
        
                    % fc = frequencia* 1e6;
        
                    aux = sprintf("sweep center %d%s", frequencia, app.prefixo(freq_count));
        
                    writeline(app.tiny,aux);
        
                    pause(1/100);
        
                    aux = sprintf("marker 1 %d", frequencia);
        
                    writeline(app.tiny,aux);
        
                    pause(1/100);
        
                    flush(app.tiny);
        
                    writeline(app.tiny,"sweeptime");
        
                    readline(app.tiny);
            
                    readline(app.tiny);
        
                    tempo = readline(app.tiny);
        
                    temp = strrep(tempo, 's', ' ');
        
                    tem = str2double(temp);
        
                    pause(tem + 1/10);
        
                    writeline(app.tiny,"marker");
        
                    pause(1/100);
        
                    readline(app.tiny);
        
                    medida = readline(app.tiny);
        
                    vetor_strg = split(medida);
                    
                    verificacao = size(vetor_strg);
                
                    veri = verificacao(1);
                    
                    valor = str2double(vetor_strg(5));
                
                    if(veri == 5 && ~isnan(valor))
                        teste = 0;
                        amp = valor;
                    else 
                        teste = 1;
                        resultado = "Erro na leitura, varrendo novamente";
                        app.STATUSTextArea.Value = resultado;
                        app.erro_leitura = app.erro_leitura + 1;
                        pause(2);
                    end
                end


        end

        function mede_campo_max(app,linha_y,coluna_x)
            flush(app.tiny);
            aux = sprintf("rbw %d", app.rbw);
            writeline(app.tiny,aux);          
            app.amps = cell(1,290);
            app.freqs = cell(1,290);
            if(isempty(app.freqs{1}))
                aux = sprintf("scan %d%s, %d%s, 290, 1", app.freq_inicial,app.prefixo_freq_inicial,app.freq_final,app.prefixo_freq_final);
                writeline(app.tiny, aux);
                pause(1/100);
                readline(app.tiny);
                pause(1/100);
                readline(app.tiny);
                i=1;
                % Enquanto ainda há bytes para serem lidos e o index não
                % ultrapassou os 290 pontos que o Tiny retorna
                while(app.tiny.NumBytesAvailable>0 && i<=290)
                    leitura_frequencia = strtok(readline(app.tiny));
                    if ~isempty(leitura_frequencia)
                        app.freqs{i} = leitura_frequencia; % Atribui o primeiro caractere de x à célula freqs
                        i = i + 1;
                    end
                    pause(1/1000);
                end
            end
            aux = sprintf("scan %d%s, %d%s, 290, 2", app.freq_inicial,app.prefixo_freq_inicial,app.freq_final,app.prefixo_freq_final);
            writeline(app.tiny, aux);
            pause(1/100);
            readline(app.tiny);
            i=1;
            while(app.tiny.NumBytesAvailable>0 && i<=290)
                leitura_amplitude = strtok(readline(app.tiny));
                if ~isempty(leitura_amplitude)
                    app.amps{i} = leitura_amplitude; % Atribui o primeiro caractere de x à célula freqs
                    i = i + 1;
                end
                pause(1/1000);
            end
            % Converte cell para double 
            app.amps = cellfun(@str2double, app.amps);
            app.freqs = cellfun(@str2double, app.freqs);
            %Amplitude máxima encontrada nas 290 frequencias daquele ponto
            %Index do ponto máximo
            [amp_pico, freq_amp_pico_idx] = max(app.amps);
            %Frequência correspondente à amplitude máxima (mesmo index)
            freq_amp_pico = app.freqs(freq_amp_pico_idx);
            %Soma todas as amplitudes nas 290 frequencias dentro da faixa
            app.soma_amps = app.soma_amps + app.amps;
            %Modifica a matriz amp_max_ponto adicionando amplitude máxima (1)
            % e frequencia correspondente (2) no ponto x,y 
            app.amp_max_ponto(linha_y,coluna_x,1) = amp_pico;
            app.amp_max_ponto(linha_y,coluna_x,2) = freq_amp_pico;
            for i=1:290
                app.todas_amplitudes(linha_y,coluna_x,i) = app.amps(i);
                app.todas_frequencias(linha_y,coluna_x,i) = app.freqs(i);
                app.amp_max_freq(i) = max(max(app.todas_amplitudes(:,:,i)));
            end
            
        end

        function liga_motor(app) %okk
                writeline(app.scanner, "E");
                resposta = readline(app.scanner);

                while(resposta ~= "OK")
                end
              
                disp("MOTOR LIGADO");
        end
        
        function desliga_motor(app) %okk
            writeline(app.scanner, "D");
            resposta = readline(app.scanner);
        
            while(resposta ~= "OK")
            end
        
            disp("MOTOR DESLIGADO");

        end
    
        function [alt]=mede_altura(app) %okk
            writeline(app.scanner, "A");
            pause(0.01);
            altura_str = readline(app.scanner);
            alt = str2double(altura_str);
        end
        function [alt_tof]=mede_altura_tof(app)
            altura_tof = strrep(readline(app.sensor), 'mm', '');
            alt_tof = str2double(altura_tof);
        end
 

        function base10 = prefixo2num(~,prefixo)
            switch prefixo
                case 'GHz'
                    base10 = (1e9);
                case 'MHz'
                    base10 = (1e6);
                case 'kHz'
                    base10 = (1e3);
                case 'Hz'
                    base10 = 1;
            end            
        end        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuscarTINYButton
        function BuscarTINYButtonPushed(app, event)
            close all;
            app.BuscarTINYButton.Enable = "Off";
            app.STATUSTextArea.Value = "Procurando TINY...";
            portas = serialportlist("available"); %lista as portas disponiveis

            tiny_nao_localizado = 1;

            for i = 1:length(portas) %percorre essas portas 

                aux1 = portas(i);

                app.tiny = serialport(aux1, 115200); %cria o objeto serial com a porta da iteração

                app.tiny.Timeout = 0.1;

                configureTerminator(app.tiny,"CR");

                writeline(app.tiny, "vbat"); %tenta comunicar 

                readline(app.tiny);

                data = readline(app.tiny);

                if(isa(data, 'string')) %se tiver retornado uma string a coneção funcionou. (Caso contrario retorna [] que é double)
                    tiny_nao_localizado = 0;

                    porta = str2double(extractBetween(aux1,4,5)); %obtem o numero da porta

                    app.PortaCOMdoTINYEditField.Value = porta;
                    app.PortaCOMdoTINYEditField.Editable = "Off";
                    app.BuscarTINYButton.Enable = 'Off';

                    app.PortaCOMdoSCANNEREditField.Enable = 'On';
                    app.PortaCOMdoSCANNEREditFieldLabel.Enable = 'On';
                    app.BuscarSCANNERButton.Enable = 'On';

                    app.STATUSTextArea.Value = "TINY encontrado";

                    break;                    
                end
            end

            if(tiny_nao_localizado)
                app.PortaCOMdoTINYEditField.Value = 99;
                app.STATUSTextArea.Value = "TINY não encontrado";
                app.BuscarTINYButton.Enable = "On";
            end
            
        end

        % Value changed function: PortaCOMdoTINYEditField
        function PortaCOMdoTINYEditFieldValueChanged(app, event)
             %clear app.tiny
            
            value = app.PortaCOMdoTINYEditField.Value;
            aux = sprintf("COM%d", value);
            app.tiny = serialport(aux,115200);
            configureTerminator(app.tiny,"CR");
       

            app.PortaCOMdoTINYEditField.Editable = "Off";
            app.BuscarTINYButton.Enable = 'Off';

            app.PortaCOMdoSCANNEREditField.Enable = 'On';
            app.PortaCOMdoSCANNEREditFieldLabel.Enable = 'On';
            app.BuscarSCANNERButton.Enable = 'On';

            app.STATUSTextArea.Value = "TINY conectado";          
        end

        % Button pushed function: BuscarSCANNERButton
        function BuscarSCANNERButtonPushed(app, event)
            app.BuscarSCANNERButton.Enable = "Off";
            app.STATUSTextArea.Value = "Buscando SCANNER...";
            portas = serialportlist("available"); %lista as portas disponiveis
            scanner_nao_localizado = 1;

            for i = 1:length(portas) %percorre essas portas 

                aux2 = portas(i);
                app.scanner = serialport(aux2, 115200);
                configureTerminator(app.scanner, 36);%%36 = $ pela tabela ASCII
                app.scanner.Timeout = 0.1;

                writeline(app.scanner, "A");
                writeline(app.scanner, "A");
                data = readline(app.scanner);
        
                if(isa(data, 'string'))   
                  
                   L = strlength(data);
                   if(L == 3)
                       scanner_nao_localizado = 0;
                       porta2 = str2double(extractBetween(aux2,4,5)); %obtem o numero da porta

                       app.PortaCOMdoSCANNEREditField.Value = porta2;
                       app.PortaCOMdoSCANNEREditField.Editable = "Off";
                       app.BuscarSCANNERButton.Enable = 'Off';
           
                       app.LarguraYmmEditField.Enable = 'On';
                       app.LarguraYmmEditFieldLabel.Enable = 'On';
            
                       app.ComprimentoXmmEditField.Enable = 'On';
                       app.ComprimentoXmmEditFieldLabel.Enable = 'On';
            
                       app.AlturadomaiorcomponentemmEditField_2.Enable = 'On';
                       app.AlturadomaiorcomponentemmEditField_2Label.Enable = 'On';
            
                       app.ResoluoPassommEditField.Enable = 'On';
                       app.ResoluoPassommEditFieldLabel.Enable = 'On';
            
                       app.DistnciaasermantidadaplacammEditField.Enable = 'On';
                       app.DistnciaasermantidadaplacammEditFieldLabel.Enable = 'On';

                       app.MODODEOPERAODropDown.Enable = 'On';
                       app.MODODEOPERAODropDownLabel.Enable = 'On';
                        
                       app.BuscarSensorButton.Enable = 'On';
                       app.PortaCOMSensorEditField.Enable = 'On';
                       app.PortaCOMSensorEditFieldLabel.Enable = 'On';


                       app.STATUSTextArea.Value = "SCANNER encontrado";
                       app.STATUSTextArea.Editable = 'Off';

                       break;              
                   end
                end
            end

            if(scanner_nao_localizado)
                app.PortaCOMdoSCANNEREditField.Value = 99;
                app.STATUSTextArea.Value = "SCANNER não encontrado";
                app.STATUSTextArea.Editable = 'Off';
                app.BuscarSCANNERButton.Enable = "On";  
            end
        end

        % Value changed function: PortaCOMdoSCANNEREditField
        function PortaCOMdoSCANNEREditFieldValueChanged(app, event)
            value = app.PortaCOMdoSCANNEREditField.Value;

            aux2 = sprintf("COM%d", value);

            app.scanner = serialport(aux2, 115200);
            configureTerminator(app.scanner, 36);%%36 = $ pela tabela ASCII
            app.scanner.Timeout = 0.1;

            app.PortaCOMdoSCANNEREditField.Editable = "Off";
            app.BuscarSCANNERButton.Enable = 'Off';

            app.NumeroDeFrequncias.Enable = 'On';
            app.NumeroDeFrequncias.Enable = 'On';

            app.LarguraYmmEditField.Enable = 'On';
            app.LarguraYmmEditFieldLabel.Enable = 'On';

            app.ComprimentoXmmEditField.Enable = 'On';
            app.ComprimentoXmmEditFieldLabel.Enable = 'On';

            app.AlturadomaiorcomponentemmEditField_2.Enable = 'On';
            app.AlturadomaiorcomponentemmEditFieldLabel.Enable = 'On';

            app.ResoluoPassommEditField.Enable = 'On';
            app.ResoluoPassommEditFieldLabel.Enable = 'On';

            app.DistnciaasermantidadaplacammEditField.Enable = 'On';
            app.DistnciaasermantidadaplacammEditFieldLabel.Enable = 'On';


            app.STATUSTextArea.Value = "SCANNER conectado";
        end

        % Callback function
        function NumerodeFrequnciasEditFieldValueChanged(app, event)
            value = app.NumerodeFrequnciasEditField.Value;

            app.num_freq = value;
            
            if (value == 0)
                 app.Freq1MHzEditField.Editable = 'Off';
                 app.Freq1MHzEditField.Visible = 'Off';
                 app.Freq1MHzEditField.Enable = 'Off';
                 app.Freq1MHzEditFieldLabel.Visible = 'Off';

                 app.Freq2MHzEditField.Editable = 'Off';
                 app.Freq2MHzEditField.Visible = 'Off';
                 app.Freq2MHzEditField.Enable = 'Off';
                 app.Freq2MHzEditFieldLabel.Visible = 'Off';

                 app.Freq3MHzEditField.Editable = 'Off';
                 app.Freq3MHzEditField.Visible = 'Off';
                 app.Freq3MHzEditField.Enable = 'Off';
                 app.Freq3MHzEditFieldLabel.Visible = 'Off';

                 app.Freq4MHzEditField.Editable = 'Off';
                 app.Freq4MHzEditField.Visible = 'Off';
                 app.Freq4MHzEditField.Enable = 'Off';
                 app.Freq4MHzEditFieldLabel.Visible = 'Off';


            elseif (value == 1)
                 app.Freq1MHzEditField.Editable = 'On';
                 app.Freq1MHzEditField.Visible = 'On';
                 app.Freq1MHzEditField.Enable = 'On';
                 app.Freq1MHzEditFieldLabel.Visible = 'On';

                 app.Freq2MHzEditField.Editable = 'Off';
                 app.Freq2MHzEditField.Visible = 'Off';
                 app.Freq2MHzEditField.Enable = 'Off';
                 app.Freq2MHzEditFieldLabel.Visible = 'Off';

                 app.Freq3MHzEditField.Editable = 'Off';
                 app.Freq3MHzEditField.Visible = 'Off';
                 app.Freq3MHzEditField.Enable = 'Off';
                 app.Freq3MHzEditFieldLabel.Visible = 'Off';

                 app.Freq4MHzEditField.Editable = 'Off';
                 app.Freq4MHzEditField.Visible = 'Off';
                 app.Freq4MHzEditField.Enable = 'Off';
                 app.Freq4MHzEditFieldLabel.Visible = 'Off';

            elseif (value == 2)
                 app.Freq1MHzEditField.Editable = 'On';
                 app.Freq1MHzEditField.Visible = 'On';
                 app.Freq1MHzEditField.Enable = 'On';
                 app.Freq1MHzEditFieldLabel.Visible = 'On';

                 app.Freq2MHzEditField.Editable = 'On';
                 app.Freq2MHzEditField.Visible = 'On';
                 app.Freq2MHzEditField.Enable = 'On';
                 app.Freq2MHzEditFieldLabel.Visible = 'On';

                 app.Freq3MHzEditField.Editable = 'Off';
                 app.Freq3MHzEditField.Visible = 'Off';
                 app.Freq3MHzEditField.Enable = 'Off';
                 app.Freq3MHzEditFieldLabel.Visible = 'Off';

                 app.Freq4MHzEditField.Editable = 'Off';
                 app.Freq4MHzEditField.Visible = 'Off';
                 app.Freq4MHzEditField.Enable = 'Off';
                 app.Freq4MHzEditFieldLabel.Visible = 'Off';


            elseif (value == 3)
                 app.Freq1MHzEditField.Editable = 'On';
                 app.Freq1MHzEditField.Visible = 'On';
                 app.Freq1MHzEditField.Enable = 'On';
                 app.Freq1MHzEditFieldLabel.Visible = 'On';

                 app.Freq2MHzEditField.Editable = 'On';
                 app.Freq2MHzEditField.Visible = 'On';
                 app.Freq2MHzEditField.Enable = 'On';
                 app.Freq2MHzEditFieldLabel.Visible = 'On';

                 app.Freq3MHzEditField.Editable = 'On';
                 app.Freq3MHzEditField.Visible = 'On';
                 app.Freq3MHzEditField.Enable = 'On';
                 app.Freq3MHzEditFieldLabel.Visible = 'On';

                 app.Freq4MHzEditField.Editable = 'Off';
                 app.Freq4MHzEditField.Visible = 'Off';
                 app.Freq4MHzEditField.Enable = 'Off';
                 app.Freq4MHzEditFieldLabel.Visible = 'Off';     
            
           
            else 
                 app.Freq1MHzEditField.Editable = 'On';
                 app.Freq1MHzEditField.Visible = 'On';
                 app.Freq1MHzEditField.Enable = 'On';
                 app.Freq1MHzEditFieldLabel.Visible = 'On';

                 app.Freq2MHzEditField.Editable = 'On';
                 app.Freq2MHzEditField.Visible = 'On';
                 app.Freq2MHzEditField.Enable = 'On';
                 app.Freq2MHzEditFieldLabel.Visible = 'On';

                 app.Freq3MHzEditField.Editable = 'On';
                 app.Freq3MHzEditField.Visible = 'On';
                 app.Freq3MHzEditField.Enable = 'On';
                 app.Freq3MHzEditFieldLabel.Visible = 'On';

                 app.Freq4MHzEditField.Editable = 'On';
                 app.Freq4MHzEditField.Visible = 'On';
                 app.Freq4MHzEditField.Enable = 'On';
                 app.Freq4MHzEditFieldLabel.Visible = 'On';
            end
        end

        % Value changed function: Freq1MHzEditField
        function Freq1MHzEditFieldValueChanged(app, event)
            value = app.Freq1MHzEditField.Value;
            app.prefixo = strings(1,4);
            app.lista_freq(1) = value;
        end

        % Value changed function: DropDownFreq2, Freq2MHzEditField
        function DropDownFreq2ValueChanged(app, event)
            app.prefixo(2) = app.DropDownFreq2.Value;         
            value = app.Freq2MHzEditField.Value;
            app.lista_freq(2) = value;
        end

        % Value changed function: Freq3MHzEditField
        function Freq3MHzEditFieldValueChanged(app, event)
            value = app.Freq3MHzEditField.Value;
            app.prefixo(3) = app.DropDownFreq3.Value;
            app.lista_freq(3) = value;
        end

        % Value changed function: Freq4MHzEditField
        function Freq4MHzEditFieldValueChanged(app, event)
            value = app.Freq4MHzEditField.Value;
            app.prefixo(4) = app.DropDownFreq4.Value;
            app.lista_freq(4) = value;
        end

        % Value changed function: ComprimentoXmmEditField
        function ComprimentoXmmEditFieldValueChanged(app, event)
            value = app.ComprimentoXmmEditField.Value;
            app.X = value;
        end

        % Value changed function: LarguraYmmEditField
        function LarguraYmmEditFieldValueChanged(app, event)
            value = app.LarguraYmmEditField.Value;
            app.Y = value;
        end

        % Value changed function: AlturadomaiorcomponentemmEditField_2
        function AlturadomaiorcomponentemmEditField_2ValueChanged(app, event)
            value = app.AlturadomaiorcomponentemmEditField_2.Value;
            app.Altura_seguranca = value;
        end

        % Value changed function: ResoluoPassommEditField
        function ResoluoPassommEditFieldValueChanged(app, event)
            value = app.ResoluoPassommEditField.Value;
            app.Res = value;
        end

        % Value changed function: DistnciaasermantidadaplacammEditField
        function DistnciaasermantidadaplacammEditFieldValueChanged(app, event)
            altura_ref = 100;
            
            app.Dist_sonda = app.DistnciaasermantidadaplacammEditField.Value;
            
            app.Dist_pl = app.Dist_sonda + app.TamanhodasondaEditField.Value;

            % Se for maior que o limite
            if(app.Dist_pl > altura_ref)
                valor_correto_dist_a_ser_mantida = altura_ref - app.TamanhodasondaEditField.Value ;

                app.DistnciaasermantidadaplacammEditField.Value = valor_correto_dist_a_ser_mantida;
                app.DistnciaasermantidadaplacammEditFieldValueChanged;

            elseif((100 + app.Dist_pl) < altura_ref)
                
                valor_correto_dist_a_ser_mantida = (altura_ref + app.TamanhodasondaEditField.Value - 110);
                %valor_correto_dist_a_ser_mantida = 50;

                app.DistnciaasermantidadaplacammEditField.Value = valor_correto_dist_a_ser_mantida;
                app.DistnciaasermantidadaplacammEditFieldValueChanged;

            end
        end

        % Value changed function: TamanhodasondaEditField
        function TamanhodasondaEditFieldValueChanged(app, event)
            app.tam_sonda = app.TamanhodasondaEditField.Value;
            app.Dist_pl = app.DistnciaasermantidadaplacammEditField.Value + app.tam_sonda;
        end

        % Value changed function: MostarmapadealturasSwitch
        function MostarmapadealturasSwitchValueChanged(app, event)
            value = app.MostarmapadealturasSwitch.Value;
            if(value == "On")
                app.mostrar_mapa_alt = 's';
            else
                app.mostrar_mapa_alt = 'n';
            end
        end

        % Value changed function: UsarescalaautomaticaSwitch
        function UsarescalaautomaticaSwitchValueChanged(app, event)
            value = app.UsarescalaautomaticaSwitch.Value;
            if (value == "On")
                app.escala_graficos = 's';
            else 
                app.escala_graficos = 'n';
            end
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            close all;
            app.scanner.Timeout = 10;

            app.tiny.Timeout = 10;

            liga_motor(app);

            mover_XYZ(app,0,0,0);

            mover_XYZ(app,10,10,10);

            mover_XYZ(app,0,0,0);

            altura_ref = mede_altura(app);


            linha_y = 1;
            coluna_x = 1;
        
            linha = 0;
            coluna = 0;
            app.n_pontos_coluna_x =(floor(app.X/app.Res)+1);
            app.n_pontos_linha_y = (floor(app.Y/app.Res)+1);
            app.amp_max_ponto = zeros(app.n_pontos_linha_y,app.n_pontos_coluna_x,2);
            altura = zeros(app.n_pontos_linha_y,app.n_pontos_coluna_x);
            altura_tof = zeros(app.n_pontos_linha_y,app.n_pontos_coluna_x);
            app.numero_de_pontos = (app.n_pontos_coluna_x)*(app.n_pontos_linha_y);
            disp(app.numero_de_pontos);

            %%%%Loop de leitura das alturas
            while linha <= app.Y
                if(coluna == 0)   %X crescente
                    while coluna <= app.X
        
                        mover_XYZ(app,coluna,linha,0);
        
                        %Mede a altura
                        %desliga_motor();
                        altura(linha_y,coluna_x) = mede_altura(app);
                        %liga_motor();
                        pause(1);
                        altura_tof(linha_y,coluna_x) = mede_altura_tof(app);
                        coluna_x = coluna_x + 1;
                        coluna = coluna + app.Res;
        
                    end
                    coluna_x = coluna_x - 1;
                    coluna = coluna - app.Res;
        
                else  %X decrescente
                    while coluna >= 0
        
                        mover_XYZ(app,coluna,linha,0);
        
                        %Mede a altura
                        %desliga_motor();
                        altura(linha_y,coluna_x) = mede_altura(app);
                        %liga_motor();
                        pause(1);
                        altura_tof(linha_y,coluna_x) = mede_altura_tof(app);
                        
                        coluna_x = coluna_x - 1;
                        coluna = coluna - app.Res;
                    end
                    coluna_x = coluna_x + 1;
                    coluna = coluna + app.Res;
        
                end
                linha_y = linha_y + 1;
                linha = linha + app.Res;
            end

            alt_seg = altura_ref - (app.Altura_seguranca + app.tam_sonda + app.Tolerancia_altura_seg);


            %%%Salva a matriz de alturas
            %%save alturas.txt altura -ascii;
    
    
            %%%%Volta para origem
            mover_XYZ(app,0,0,0);
	

            %%%Corrige para a altura de referencia valores maiores que ela
            for i=1:length(altura(:,1))
                for j=1:length(altura(1,:))
                    if(altura(i,j) > altura_ref)
                        altura(i,j) = altura_ref;
                        altura_tof(i,j) = altura_ref;
                    end
                end
            end

            %%%Calcula as alturas para serem mantidas
    
            alturas_z = altura - app.Dist_pl;
            alturas_z_tof = altura_tof - app.Dist_pl;
    
            %%Prote��o contra numeros negativos em alturas_z
            for i=1:length(alturas_z(:,1))
                for j=1:length(alturas_z(1,:))
                    if(alturas_z(i,j) < 0)
                        alturas_z(i,j) = 0;
                        alturas_z_tof(i,j) = 0;
                    end
                end
            end


            Razao_subida_max = 2;

            %%%%Faz o afastamento dos objetos
            %%%Eixo X
    
            altura_ant = alturas_z(1,1);
            for i=1:length(alturas_z(:,1))
                for j=1:length(alturas_z(1,:))
                    %%%Descendo
                    if(alturas_z(i,j) > altura_ant)
                        if(((alturas_z(i,j) - altura_ant) / app.Res) > Razao_subida_max)  %% Calcula razao de subida - se for maior que 5mm a cada 1 mm aciona o algoritimo
                            if(j == 1)
                                alturas_z(i,j) = alturas_z(i,j);
                            else
                                alturas_z(i,(j + 1)) = alturas_z(i,j);
                            end
                        end
                    end
                    %%%Subindo
                    if(alturas_z(i,j) < altura_ant)
                        if(((altura_ant - alturas_z(i,j)) / app.Res) > Razao_subida_max)  %% Calcula razao de subida - se for maior que 5mm a cada 1 mm aciona o algoritimo
                            if(j <= 1)
                                alturas_z(i,j) = alturas_z(i,j);
                            else
                                alturas_z(i,(j - 1)) = alturas_z(i,j);
                            end
                        end
                    end
            
                    altura_ant = alturas_z(i,j);
                end
            end
    
            %%Eixo Y
            i = 1;
            j = 1;
    
            altura_ant = alturas_z(1,1);
            for j=1:length(alturas_z(1,:))
                for i=1:length(alturas_z(:,1))
                    %%%Descendo
                    if(alturas_z(i,j) > altura_ant)
                        if(((alturas_z(i,j) - altura_ant) / app.Res) > Razao_subida_max)  %% Calcula razao de subida - se for maior que 5mm a cada 1 mm aciona o algoritimo
                            if(i == 1)
                                alturas_z(i,j) = alturas_z(i,j);
                            else
                                alturas_z((i + 1),j) = alturas_z(i,j);
                            end
                        end
                    end
                    %%%Subindo
                    if(alturas_z(i,j) < altura_ant)
                        if(((altura_ant - alturas_z(i,j)) / app.Res) > Razao_subida_max)  %% Calcula razao de subida - se for maior que 5mm a cada 1 mm aciona o algoritimo
                            if(i <= 1)
                                alturas_z(i,j) = alturas_z(i,j);
                            else
                                alturas_z((i - 1),j) = alturas_z(i,j);
                            end
                        end
                    end
            
                    altura_ant = alturas_z(i,j);
                end
            end

            %%%Plota as alturas originais (TRIANGULAÇÃO)
            aux_altura = altura_ref - altura;
            if(app.mostrar_mapa_alt == 's')
     
                figure;
                %%surf(aux_altura);
                X_aux = (0:app.Res:app.X);
                Y_aux = (0:app.Res:app.Y);
                [X_aux,Y_aux]=meshgrid(X_aux,Y_aux);
                surf(X_aux,Y_aux,aux_altura)
                
                title('OBJETO DIGITALIZADO');
                ylabel('Largura [mm]')
                xlabel('Comprimento [mm]')
                zlabel('Altura [mm]')
                
                drawnow;
                
                hold on;
                 
                %Salva as alturas
                str_aux = sprintf('%s_alt','teste');
                savefig(str_aux);
                
            %%%Plota altura (TOF)
            aux_altura = altura_ref - altura;
            if(app.mostrar_mapa_alt == 's')
     
                figure;
                %%surf(aux_altura);
                X_aux = (0:app.Res:app.X);
                Y_aux = (0:app.Res:app.Y);
                [X_aux,Y_aux]=meshgrid(X_aux,Y_aux);
                surf(X_aux,Y_aux,aux_altura)
                
                title('OBJETO DIGITALIZADO');
                ylabel('Largura [mm]')
                xlabel('Comprimento [mm]')
                zlabel('Altura [mm]')
                
                drawnow;
                
                hold on;
                 
                %Salva as alturas
                str_aux = sprintf('%s_alt','teste');
                savefig(str_aux);

            end
    

            linha_y = 1;
            coluna_x = 1;
            
            linha = 0;
            linha_old_flag = 0;
            coluna = 0;
            coluna_old = 0;
            pontos_lidos = 0;
            %mover_z = alturas_z(linha_y,coluna_x);
            mover_z_ant = alturas_z(linha_y,coluna_x);


            while linha <= app.Y
                
                if(coluna == 0)   %X crescente
                    while coluna <= app.X

                        mover_z = alturas_z(linha_y,coluna_x);
                
                        if(mover_z == mover_z_ant)
                            mover_XYZ(app,(coluna + app.Desloc_sensor_X),(linha + app.Desloc_sensor_Y),mover_z);
                        else
                            if(linha_old_flag == 1)  
                                mover_XYZ(app,(coluna + app.Desloc_sensor_X),((linha - 1) + app.Desloc_sensor_Y),alt_seg);
                                linha_old_flag = 0;
                            else
                                mover_XYZ(app,(coluna_old + app.Desloc_sensor_X),(linha + app.Desloc_sensor_Y),alt_seg);
                            end 
                            mover_XYZ(app,(coluna + app.Desloc_sensor_X),(linha + app.Desloc_sensor_Y),alt_seg);
				    
	                        mover_XYZ(app,(coluna + app.Desloc_sensor_X),(linha + app.Desloc_sensor_Y),mover_z); 
                        end
    
                        %%%Mede o campo
                        desliga_motor(app);
                        switch app.modo_operacao
                            case 'Varredura Completa'
                                mede_campo_max(app,linha_y,coluna_x);
                            case 'Frequências Específicas'
                                for freq_count=1:app.num_freq
                                    [amp_campo(linha_y,coluna_x,freq_count)] = mede_campo(app,freq_count);
                                end
                        end
                        
                        liga_motor(app);
                
                        % buf_aux = sprintf("linha_y:%d coluna_x:%d coluna_old:%d linha:%d coluna:%d  \n",linha_y,coluna_x,coluna_old,linha,coluna);
                        % disp(buf_aux);
               
                        if(app.mostrar_mapa_alt == 's')
                            
                            scatter3(coluna,linha,aux_altura(linha_y,coluna_x),'*','w');
                            drawnow;
                            pontos_lidos = pontos_lidos+1;
                            progresso = (pontos_lidos*100)/(app.numero_de_pontos);
                            app.STATUSTextArea.Value = (['Progresso: ',num2str(progresso),'%']);
    
                        end
                        %%%Mede o campo
                        mover_z_ant = mover_z;
                
                        coluna_x = coluna_x + 1;
                        coluna_old = coluna;
                        coluna = coluna + app.Res;
                
                
                    end
                    linha_old_flag = 1;
                    coluna_x = coluna_x - 1;
                    coluna = coluna - app.Res;  
        
        
                else  %X decrescente
                    while coluna >= 0

                        mover_z = alturas_z(linha_y,coluna_x);
                
                        if(mover_z == mover_z_ant)
                            mover_XYZ(app,(coluna + app.Desloc_sensor_X),(linha + app.Desloc_sensor_Y),mover_z);
                        else
                            if(linha_old_flag == 1)  
                                mover_XYZ(app,(coluna + app.Desloc_sensor_X),((linha - 1) + app.Desloc_sensor_Y),alt_seg);
                                linha_old_flag = 0;
                            else
                                mover_XYZ(app,(coluna_old + app.Desloc_sensor_X),(linha + app.Desloc_sensor_Y),alt_seg);
                            end 
                            mover_XYZ(app,(coluna + app.Desloc_sensor_X),(linha + app.Desloc_sensor_Y),alt_seg);
				    
	                        mover_XYZ(app,(coluna + app.Desloc_sensor_X),(linha + app.Desloc_sensor_Y),mover_z); 
                        end
                
                        buf_aux = sprintf("Progresso: MX%03d.0Y%03d.0 (X%dY%d)",coluna,linha,app.X,app.Y);
                        disp(buf_aux);           
                
                
                        %%%Mede o campo
                
                        desliga_motor(app);
                        switch app.modo_operacao
                        case 'Varredura Completa'
                            mede_campo_max(app,linha_y,coluna_x);
                        case 'Frequências Específicas'
                            for freq_count=1:app.num_freq
                                [amp_campo(linha_y,coluna_x,freq_count)] = mede_campo(app,freq_count);
                            end
                        end
                        liga_motor(app);
                
                        if(app.mostrar_mapa_alt == 's')
                
                            scatter3(coluna,linha,aux_altura(linha_y,coluna_x),'x','w');
                            drawnow;
                            pontos_lidos = pontos_lidos+1;
                            progresso = (pontos_lidos*100)/(app.numero_de_pontos);
                            app.STATUSTextArea.Value = (['Progresso: ',num2str(progresso),'%']);
                
                        end
                        mover_z_ant = mover_z;
                        coluna_x = coluna_x - 1;
                        coluna_old = coluna;
                        coluna = coluna - app.Res;
                
                
                    end
                    linha_old_flag = 1;
                    coluna_x = coluna_x + 1;
                    coluna = coluna + app.Res;
                
                end
                linha_y = linha_y + 1;
                linha = linha + app.Res;
           
            end
    
            %%%%Volta para a altura de origem - altura
            mover_XYZ(app,(coluna + app.Desloc_sensor_X),(linha + app.Desloc_sensor_Y),0);
    
     
            %%%%Volta para origem - XY
            mover_XYZ(app,0,0,0);
            desliga_motor(app);
      
            %%%%%Mede a altura final
            altura_final = mede_altura(app);
    
            %%%Mostra mapa de alturas e estatisticas
    
            erro_altura = altura_final - altura_ref;
 
            buf_aux = sprintf("ERRO DE ALTURA: %d  \n",erro_altura);
            disp(buf_aux); 
     
            %%%%%Faz o mapa de alturas se n�o tiver feito antes
            if(app.mostrar_mapa_alt == 'n')
              
                figure;
         
                %%surf(aux_altura);
                X_aux = (0:app.Res:app.X);
                Y_aux = (0:app.Res:app.Y);
                [X_aux,Y_aux]=meshgrid(X_aux,Y_aux);
                surf(X_aux,Y_aux,aux_altura);
                
                title('OBJETO DIGITALIZADO');
                ylabel('Largura [mm]')
                xlabel('Comprimento [mm]')
                zlabel('Altura [mm]')
         
                drawnow;
         
                hold on;
         
                %%%%Salva as alturas
                str_aux = sprintf('%s_alt',app.nome);
                savefig(str_aux);
            end    
     
            str_aux = sprintf('%s.mat',app.nome);

            save (str_aux);


            %%%%%Faz os mapas do campo
            switch app.modo_operacao
                case 'Varredura Completa'
                    disp('amp_max_ponto\n');
                    disp(app.amp_max_ponto);
                    if(app.escala_graficos == 'n')
                        valor_minimo = min(min(app.amp_max_ponto));
                        valor_maximo = max(max(app.amp_max_ponto));
                    end


                    plot(app.UIAxes,app.freqs,app.amp_max_freq,"red"); % Plotar um gráfico de exemplo
                    title(app.UIAxes, 'Amplitude Máxima x Frequência'); % Adicionar um título
                    xlabel(app.UIAxes, 'Frequencia [Hz]');
                    ylabel(app.UIAxes, 'Amplitude [dBm]');

                    dados_mapa_de_calor = zeros(app.n_pontos_linha_y,app.n_pontos_coluna_x);
                    for i=1:app.n_pontos_linha_y
                        for j=1:app.n_pontos_coluna_x
                            dados_mapa_de_calor(i,j) = app.todas_amplitudes(i,j,1);
                        end
                    end
                    X_aux = (0:app.Res:app.X);
                    Y_aux = (0:app.Res:app.Y);
                    [X_aux,Y_aux]=meshgrid(X_aux,Y_aux);
                    contourf(app.UIAxes2,X_aux,Y_aux,dados_mapa_de_calor(:,:),100,'LineStyle','none');
                    colormap(app.UIAxes2,'jet');
                    c_amp = colorbar(app.UIAxes2);
                    c_amp.Label.String = 'Campo Eletrico [dBm]';
                    set(findall(gcf,'-property','FontSize'),'FontSize',14);
                    set(gcf,'units','centimeters','position',[5,5,app.X/3,app.Y/3]);

                    buf_aux = sprintf("OBJETO DIGITALIZADO - DISTRIBUICAO CAMPOS");
                    title(app.UIAxes2,buf_aux);
                    ylabel(app.UIAxes2,'Largura [mm]')
                    xlabel(app.UIAxes2,'Comprimento [mm]')


                    drawnow;

                    hold on;

                    X_aux = (0:app.Res:app.X);
                    Y_aux = (0:app.Res:app.Y);
                    [X_aux,Y_aux]=meshgrid(X_aux,Y_aux);
                    contourf(app.UIAxesCampo,X_aux,Y_aux,app.amp_max_ponto(:,:,1),100,'LineStyle','none');
                    colormap(app.UIAxesCampo,'jet');
                    c_amp = colorbar(app.UIAxesCampo);
                    c_amp.Label.String = 'Campo Eletrico [dBm]';
                    set(findall(gcf,'-property','FontSize'),'FontSize',14);
                    set(gcf,'units','centimeters','position',[5,5,app.X/3,app.Y/3]);

                    if(app.escala_graficos == 'n')
                        clim(app.UIAxesCampo,[valor_minimo valor_maximo]);
                    end

                    buf_aux = sprintf("OBJETO DIGITALIZADO - AMPLITUDE MÁXIMA EM CADA PONTO");
                    title(app.UIAxesCampo,buf_aux);
                    ylabel(app.UIAxesCampo,'Largura [mm]')
                    xlabel(app.UIAxesCampo,'Comprimento [mm]')


                    drawnow;

                    hold on;

                    X_aux = (0:app.Res:app.X);
                    Y_aux = (0:app.Res:app.Y);
                    [X_aux,Y_aux]=meshgrid(X_aux,Y_aux);
                    contourf(app.UIAxesFreq,X_aux,Y_aux,app.amp_max_ponto(:,:,2),100,'LineStyle','none');
                    colormap(app.UIAxesFreq,'jet');
                    c_amp = colorbar(app.UIAxesFreq);
                    c_amp.Label.String = 'Frequência [Hz]';
                    set(findall(gcf,'-property','FontSize'),'FontSize',14);
                    set(gcf,'units','centimeters','position',[5,5,app.X/3,app.Y/3]);

                    if(app.escala_graficos == 'n')
                        clim(app.UIAxesCampo,[valor_minimo valor_maximo]);
                    end

                    buf_aux = sprintf("OBJETO DIGITALIZADO - FREQUÊNCIAS CORRESPONDENTES");
                    title(app.UIAxesFreq,buf_aux);
                    ylabel(app.UIAxesFreq,'Largura [mm]')
                    xlabel(app.UIAxesFreq,'Comprimento [mm]')


                    drawnow;

                    hold on;

                    %%%%Salva o campo
                    str_aux = sprintf('%s_amp_max',app.nome);
                    savefig(str_aux);
                    str_aux = sprintf('%s_amp_max.png',app.nome);
                    saveas(gcf,str_aux);
                    app.TabGroup.SelectedTab = app.CAMPOSTab;

                case 'Frequências Específicas'
                    if(app.escala_graficos == 'n')
                        valor_minimo = min(min(min(app.amp_max)));
                        valor_maximo = max(max(max(app.amp_max)));
                    end

                    for freq_count=1:app.num_freq
                        figure;

                        X_aux = (0:app.Res:app.X);
                        Y_aux = (0:app.Res:app.Y);
                        [X_aux,Y_aux]=meshgrid(X_aux,Y_aux);
                        contourf(X_aux,Y_aux,amp_campo(:,:,freq_count),100,'LineStyle','none');
                        colormap('jet');
                        c_amp = colorbar;
                        c_amp.Label.String = 'Campo Eletrico [dBuV]';
                        set(findall(gcf,'-property','FontSize'),'FontSize',14);
                        set(gcf,'units','centimeters','position',[5,5,app.X/3,app.Y/3]);

                        if(app.escala_graficos == 'n')
                            clim([valor_minimo valor_maximo]);
                        end

                        buf_aux = sprintf("OBJETO DIGITALIZADO - DISTRIBUICAO CAMPOS - FREQUENCIA: %.3f %s",app.lista_freq(freq_count), app.prefixo(freq_count));
                        title(buf_aux);
                        ylabel('Largura [mm]')
                        xlabel('Comprimento [mm]')


                        drawnow;

                        hold on;

                        %%%%Salva o campo
                        str_aux = sprintf('%s_amp_%d',app.nome,freq_count);
                        savefig(str_aux);
                        str_aux = sprintf('%s_amp_%d.png',app.nome,freq_count);
                        saveas(gcf,str_aux);

                    end
            end
                app.desliga_motor();
            end
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureCloseRequest(app, event)
            app.scanner = [];
            clear app.scanner; 
            delete(app)
            
        end

        % Button pushed function: moverButton
        function moverButtonPushed(app, event)
           x = 10;
           y = 10;
           z = 10;
            
           buf = sprintf("MX%03d.0Y%03d.0Z%03d.0",x,y,z);
           writeline(app.scanner, buf);

           a = 1;

           while(a)
               resposta = readline(app.scanner);
               if(isa(resposta, "string"))
                   a = 0;
               end
           end

           s = strcat("OK mov ", buf);


        end

        % Button pushed function: medealturaButton
        function medealturaButtonPushed(app, event)
            writeline(app.scanner, "A");
            pause(0.01);
            altura_str = readline(app.scanner);
            %altura_str(4) = 0;
            alt = str2double(altura_str);
            alt2 = readline(app.sensor);
            app.STATUSTextArea.Value = "IR: "+ num2str(alt) +"/TOF: "+ alt2;
        end

        % Value changed function: NomedoArquivoEditField
        function NomedoArquivoEditFieldValueChanged(app, event)
            value = app.NomedoArquivoEditField.Value;
            app.nome = value;
        end

        % Value changed function: 
        % DistanciaentresensordealturaesondaemXmmEditField
        function DistanciaentresensordealturaesondaemXmmEditFieldValueChanged(app, event)
            value = app.DistanciaentresensordealturaesondaemXmmEditField.Value;
            app.Desloc_sensor_X = value;
        end

        % Value changed function: 
        % DistanciaentresensordealturaesondaemYmmEditField
        function DistanciaentresensordealturaesondaemYmmEditFieldValueChanged(app, event)
            value = app.DistanciaentresensordealturaesondaemYmmEditField.Value;
            app.Desloc_sensor_Y = value;
        end

        % Callback function
        function VARREDURARPIDAButtonPushed(app, event)
            
        end

        % Button pushed function: medecampoButton
        function medecampoButtonPushed(app, event)
            mede_campo_max(app,1,1);
            app.TabGroup.SelectedTab = app.CAMPOSTab;
            disp(app.todas_amplitudes);
            disp(app.todas_frequencias);
            plot(app.UIAxes,app.freqs,app.amp_max_freq,"red"); % Plotar um gráfico de exemplo
            title(app.UIAxes, 'Amplitude Máxima x Frequência'); % Adicionar um título
            xlabel(app.UIAxes, 'Frequencia [Hz]');
            ylabel(app.UIAxes, 'Amplitude [dBm]');
        end

        % Callback function
        function MostarmapadealturasSwitch_2ValueChanged(app, event)

        end

        % Value changed function: MODODEOPERAODropDown
        function MODODEOPERAODropDownValueChanged(app, event)
            value = app.MODODEOPERAODropDown.Value;
            app.modo_operacao = value;
            switch app.modo_operacao
                case 'Selecione'
                    app.STATUSTextArea.Value = "Selecione o modo de operação";
                case 'Varredura Completa'
                    app.FreqInicialEditField.Visible = "on";
                    app.FreqInicialEditFieldLabel.Visible = "on";
                    app.FreqFinalEditField.Visible = "on";
                    app.FreqFinalEditFieldLabel.Visible = "on";
                    app.TempoEstimadoEditField.Visible = "on";
                    app.TempoEstimadoEditFieldLabel.Visible = "on";
                    app.DropDownRBW.Visible = "on";
                    app.RBWkHzEditFieldLabel.Visible = "on";
                    app.RBWkHzEditFieldLabel.Visible = "on";
                    app.DropDownFreqFinal.Visible = "on";
                    app.DropDownFreqInicial.Visible = "on";
                    app.NumerodefrequenciasSpinner.Visible = "off";
                    app.NumerodefrequenciasSpinnerLabel.Visible = "off";
                    app.Freq1MHzEditField.Visible = "off";
                    app.Freq1MHzEditFieldLabel.Visible = "off";
                    app.DropDownFreq1.Visible = 'Off';
                    app.Freq2MHzEditField.Visible = "off";
                    app.Freq2MHzEditFieldLabel.Visible = "off";
                    app.DropDownFreq2.Visible = 'Off';
                    app.Freq3MHzEditField.Visible = "off";
                    app.Freq3MHzEditFieldLabel.Visible = "off";
                    app.DropDownFreq3.Visible = 'Off';
                    app.Freq4MHzEditField.Visible = "off";
                    app.Freq4MHzEditFieldLabel.Visible = "off";
                    app.DropDownFreq4.Visible = 'Off';
                    app.IniciarButton.Enable = 'On';
                    app.STATUSTextArea.Value = "";
                case 'Frequências Específicas'
                    app.NumerodefrequenciasSpinner.Visible = "on";
                    app.NumerodefrequenciasSpinnerLabel.Visible = "on";
                    app.FreqInicialEditField.Visible = "off";
                    app.FreqInicialEditFieldLabel.Visible = "off";
                    app.FreqFinalEditField.Visible = "off";
                    app.FreqFinalEditFieldLabel.Visible = "off";
                    app.TempoEstimadoEditField.Visible = "off";
                    app.TempoEstimadoEditFieldLabel.Visible = "off";
                    app.DropDownRBW.Visible = "off";
                    app.RBWkHzEditFieldLabel.Visible = "off";
                    app.DropDownFreqFinal.Visible = "off";
                    app.DropDownFreqInicial.Visible = "off";
                    app.IniciarButton.Enable = 'On';
                    app.STATUSTextArea.Value = "";
            end
        end

        % Value changed function: DropDownFreqInicial
        function DropDownFreqInicialValueChanged(app, event)
            app.prefixo_freq_inicial = app.DropDownFreqInicial.Value;
            app.base10_freq_inicial = prefixo2num(app,app.prefixo_freq_inicial);
            
        end

        % Value changed function: DropDownFreqFinal
        function DropDownFreqFinalValueChanged(app, event)
            app.prefixo_freq_final = app.DropDownFreqFinal.Value;
            app.base10_freq_final = prefixo2num(app,app.prefixo_freq_final);
            
        end

        % Callback function
        function DropDownRBWValueChanged(app, event)

        end

        % Callback function
        function NumeroDeFrequenciasValueChanged(app, event)
            value = app.NumerodefrequenciasSpinner.Value;
            
            app.num_freq = value;
            
            if (value == 0)
                 app.Freq1MHzEditField.Editable = 'Off';
                 app.Freq1MHzEditField.Visible = 'Off';
                 app.Freq1MHzEditField.Enable = 'Off';
                 app.Freq1MHzEditFieldLabel.Visible = 'Off';

                 app.Freq2MHzEditField.Editable = 'Off';
                 app.Freq2MHzEditField.Visible = 'Off';
                 app.Freq2MHzEditField.Enable = 'Off';
                 app.Freq2MHzEditFieldLabel.Visible = 'Off';

                 app.Freq3MHzEditField.Editable = 'Off';
                 app.Freq3MHzEditField.Visible = 'Off';
                 app.Freq3MHzEditField.Enable = 'Off';
                 app.Freq3MHzEditFieldLabel.Visible = 'Off';

                 app.Freq4MHzEditField.Editable = 'Off';
                 app.Freq4MHzEditField.Visible = 'Off';
                 app.Freq4MHzEditField.Enable = 'Off';
                 app.Freq4MHzEditFieldLabel.Visible = 'Off';


            elseif (value == 1)
                 app.Freq1MHzEditField.Editable = 'On';
                 app.Freq1MHzEditField.Visible = 'On';
                 app.Freq1MHzEditField.Enable = 'On';
                 app.Freq1MHzEditFieldLabel.Visible = 'On';

                 app.Freq2MHzEditField.Editable = 'Off';
                 app.Freq2MHzEditField.Visible = 'Off';
                 app.Freq2MHzEditField.Enable = 'Off';
                 app.Freq2MHzEditFieldLabel.Visible = 'Off';

                 app.Freq3MHzEditField.Editable = 'Off';
                 app.Freq3MHzEditField.Visible = 'Off';
                 app.Freq3MHzEditField.Enable = 'Off';
                 app.Freq3MHzEditFieldLabel.Visible = 'Off';

                 app.Freq4MHzEditField.Editable = 'Off';
                 app.Freq4MHzEditField.Visible = 'Off';
                 app.Freq4MHzEditField.Enable = 'Off';
                 app.Freq4MHzEditFieldLabel.Visible = 'Off';

            elseif (value == 2)
                 app.Freq1MHzEditField.Editable = 'On';
                 app.Freq1MHzEditField.Visible = 'On';
                 app.Freq1MHzEditField.Enable = 'On';
                 app.Freq1MHzEditFieldLabel.Visible = 'On';

                 app.Freq2MHzEditField.Editable = 'On';
                 app.Freq2MHzEditField.Visible = 'On';
                 app.Freq2MHzEditField.Enable = 'On';
                 app.Freq2MHzEditFieldLabel.Visible = 'On';

                 app.Freq3MHzEditField.Editable = 'Off';
                 app.Freq3MHzEditField.Visible = 'Off';
                 app.Freq3MHzEditField.Enable = 'Off';
                 app.Freq3MHzEditFieldLabel.Visible = 'Off';

                 app.Freq4MHzEditField.Editable = 'Off';
                 app.Freq4MHzEditField.Visible = 'Off';
                 app.Freq4MHzEditField.Enable = 'Off';
                 app.Freq4MHzEditFieldLabel.Visible = 'Off';


            elseif (value == 3)
                 app.Freq1MHzEditField.Editable = 'On';
                 app.Freq1MHzEditField.Visible = 'On';
                 app.Freq1MHzEditField.Enable = 'On';
                 app.Freq1MHzEditFieldLabel.Visible = 'On';

                 app.Freq2MHzEditField.Editable = 'On';
                 app.Freq2MHzEditField.Visible = 'On';
                 app.Freq2MHzEditField.Enable = 'On';
                 app.Freq2MHzEditFieldLabel.Visible = 'On';

                 app.Freq3MHzEditField.Editable = 'On';
                 app.Freq3MHzEditField.Visible = 'On';
                 app.Freq3MHzEditField.Enable = 'On';
                 app.Freq3MHzEditFieldLabel.Visible = 'On';

                 app.Freq4MHzEditField.Editable = 'Off';
                 app.Freq4MHzEditField.Visible = 'Off';
                 app.Freq4MHzEditField.Enable = 'Off';
                 app.Freq4MHzEditFieldLabel.Visible = 'Off';     
            
           
            else 
                 app.Freq1MHzEditField.Editable = 'On';
                 app.Freq1MHzEditField.Visible = 'On';
                 app.Freq1MHzEditField.Enable = 'On';
                 app.Freq1MHzEditFieldLabel.Visible = 'On';

                 app.Freq2MHzEditField.Editable = 'On';
                 app.Freq2MHzEditField.Visible = 'On';
                 app.Freq2MHzEditField.Enable = 'On';
                 app.Freq2MHzEditFieldLabel.Visible = 'On';

                 app.Freq3MHzEditField.Editable = 'On';
                 app.Freq3MHzEditField.Visible = 'On';
                 app.Freq3MHzEditField.Enable = 'On';
                 app.Freq3MHzEditFieldLabel.Visible = 'On';

                 app.Freq4MHzEditField.Editable = 'On';
                 app.Freq4MHzEditField.Visible = 'On';
                 app.Freq4MHzEditField.Enable = 'On';
                 app.Freq4MHzEditFieldLabel.Visible = 'On';
            end

        end

        % Value changed function: NumerodefrequenciasSpinner
        function NumerodefrequenciasSpinnerValueChanged2(app, event)
            value = app.NumerodefrequenciasSpinner.Value;
            app.num_freq = value;
            
            if (value == 0)
                 app.Freq1MHzEditField.Editable = 'Off';
                 app.Freq1MHzEditField.Visible = 'Off';
                 app.Freq1MHzEditField.Enable = 'Off';
                 app.Freq1MHzEditFieldLabel.Visible = 'Off';

                 app.Freq2MHzEditField.Editable = 'Off';
                 app.Freq2MHzEditField.Visible = 'Off';
                 app.Freq2MHzEditField.Enable = 'Off';
                 app.Freq2MHzEditFieldLabel.Visible = 'Off';

                 app.Freq3MHzEditField.Editable = 'Off';
                 app.Freq3MHzEditField.Visible = 'Off';
                 app.Freq3MHzEditField.Enable = 'Off';
                 app.Freq3MHzEditFieldLabel.Visible = 'Off';

                 app.Freq4MHzEditField.Editable = 'Off';
                 app.Freq4MHzEditField.Visible = 'Off';
                 app.Freq4MHzEditField.Enable = 'Off';
                 app.Freq4MHzEditFieldLabel.Visible = 'Off';


            elseif (value == 1)
                 app.Freq1MHzEditField.Editable = 'On';
                 app.Freq1MHzEditField.Visible = 'On';
                 app.Freq1MHzEditField.Enable = 'On';
                 app.Freq1MHzEditFieldLabel.Visible = 'On';
                 app.DropDownFreq1.Visible = 'On';

                 app.Freq2MHzEditField.Editable = 'Off';
                 app.Freq2MHzEditField.Visible = 'Off';
                 app.Freq2MHzEditField.Enable = 'Off';
                 app.Freq2MHzEditFieldLabel.Visible = 'Off';
                 app.DropDownFreq2.Visible = 'Off';

                 app.Freq3MHzEditField.Editable = 'Off';
                 app.Freq3MHzEditField.Visible = 'Off';
                 app.Freq3MHzEditField.Enable = 'Off';
                 app.Freq3MHzEditFieldLabel.Visible = 'Off';
                 app.DropDownFreq3.Visible = 'Off';

                 app.Freq4MHzEditField.Editable = 'Off';
                 app.Freq4MHzEditField.Visible = 'Off';
                 app.Freq4MHzEditField.Enable = 'Off';
                 app.Freq4MHzEditFieldLabel.Visible = 'Off';
                 app.DropDownFreq4.Visible = 'Off';

            elseif (value == 2)
                 app.Freq1MHzEditField.Editable = 'On';
                 app.Freq1MHzEditField.Visible = 'On';
                 app.Freq1MHzEditField.Enable = 'On';
                 app.Freq1MHzEditFieldLabel.Visible = 'On';
                 app.DropDownFreq1.Visible = 'On';

                 app.Freq2MHzEditField.Editable = 'On';
                 app.Freq2MHzEditField.Visible = 'On';
                 app.Freq2MHzEditField.Enable = 'On';
                 app.Freq2MHzEditFieldLabel.Visible = 'On';
                 app.DropDownFreq2.Visible = 'On';

                 app.Freq3MHzEditField.Editable = 'Off';
                 app.Freq3MHzEditField.Visible = 'Off';
                 app.Freq3MHzEditField.Enable = 'Off';
                 app.Freq3MHzEditFieldLabel.Visible = 'Off';
                 app.DropDownFreq3.Visible = 'Off';

                 app.Freq4MHzEditField.Editable = 'Off';
                 app.Freq4MHzEditField.Visible = 'Off';
                 app.Freq4MHzEditField.Enable = 'Off';
                 app.Freq4MHzEditFieldLabel.Visible = 'Off';
                 app.DropDownFreq4.Visible = 'Off';


            elseif (value == 3)
                 app.Freq1MHzEditField.Editable = 'On';
                 app.Freq1MHzEditField.Visible = 'On';
                 app.Freq1MHzEditField.Enable = 'On';
                 app.Freq1MHzEditFieldLabel.Visible = 'On';

                 app.Freq2MHzEditField.Editable = 'On';
                 app.Freq2MHzEditField.Visible = 'On';
                 app.Freq2MHzEditField.Enable = 'On';
                 app.Freq2MHzEditFieldLabel.Visible = 'On';
                 app.DropDownFreq2.Visible = 'On';

                 app.Freq3MHzEditField.Editable = 'On';
                 app.Freq3MHzEditField.Visible = 'On';
                 app.Freq3MHzEditField.Enable = 'On';
                 app.Freq3MHzEditFieldLabel.Visible = 'On';
                 app.DropDownFreq3.Visible = 'On';

                 app.Freq4MHzEditField.Editable = 'Off';
                 app.Freq4MHzEditField.Visible = 'Off';
                 app.Freq4MHzEditField.Enable = 'Off';
                 app.Freq4MHzEditFieldLabel.Visible = 'Off';
                 app.DropDownFreq4.Visible = 'Off';
            
           
            else 
                 app.Freq1MHzEditField.Editable = 'On';
                 app.Freq1MHzEditField.Visible = 'On';
                 app.Freq1MHzEditField.Enable = 'On';
                 app.Freq1MHzEditFieldLabel.Visible = 'On';
                 app.DropDownFreq1.Visible = 'On';

                 app.Freq2MHzEditField.Editable = 'On';
                 app.Freq2MHzEditField.Visible = 'On';
                 app.Freq2MHzEditField.Enable = 'On';
                 app.Freq2MHzEditFieldLabel.Visible = 'On';
                 app.DropDownFreq2.Visible = 'On';

                 app.Freq3MHzEditField.Editable = 'On';
                 app.Freq3MHzEditField.Visible = 'On';
                 app.Freq3MHzEditField.Enable = 'On';
                 app.Freq3MHzEditFieldLabel.Visible = 'On';
                 app.DropDownFreq3.Visible = 'On';

                 app.Freq4MHzEditField.Editable = 'On';
                 app.Freq4MHzEditField.Visible = 'On';
                 app.Freq4MHzEditField.Enable = 'On';
                 app.Freq4MHzEditFieldLabel.Visible = 'On';
                 app.DropDownFreq4.Visible = 'On';
            end
        end

        % Value changed function: DropDownRBW
        function DropDownRBWValueChanged2(app, event)
           value = app.DropDownRBW.Value;
           app.rbw = str2double(value);
           
        end

        % Value changed function: FreqInicialEditField
        function FreqInicialEditFieldValueChanged(app, event)
            value = app.FreqInicialEditField.Value;
            app.freq_inicial = str2double(value);
            
        end

        % Value changed function: FreqFinalEditField
        function FreqFinalEditFieldValueChanged(app, event)
            value = app.FreqFinalEditField.Value;
            app.freq_final = str2double(value);
           
        end

        % Value changed function: DropDownFreq1
        function DropDownFreq1ValueChanged(app, event)
            app.prefixo(1) = app.DropDownFreq1.Value;
        end

        % Value changed function: DropDownFreq3
        function DropDownFreq3ValueChanged(app, event)
            app.prefixo(3) = app.DropDownFreq3.Value;
        end

        % Value changed function: DropDownFreq4
        function DropDownFreq4ValueChanged(app, event)
            app.prefixo(4) = app.DropDownFreq4.Value;
        end

        % Value changed function: FrequenciaSpinner
        function FrequenciaSpinnerValueChanged(app, event)
            value = app.FrequenciaSpinner.Value;
            app.cursor_freq = app.freqs(value);
            app.EditField.Value = app.cursor_freq;
            dados_mapa_de_calor = zeros(app.n_pontos_linha_y,app.n_pontos_coluna_x);
            for i=1:app.n_pontos_linha_y
                for j=1:app.n_pontos_coluna_x
                    dados_mapa_de_calor(i,j) = app.todas_amplitudes(i,j,value);
                end
            end
            X_aux = (0:app.Res:app.X);
            Y_aux = (0:app.Res:app.Y);
            [X_aux,Y_aux]=meshgrid(X_aux,Y_aux);
            contourf(app.UIAxes2,X_aux,Y_aux,dados_mapa_de_calor(:,:),100,'LineStyle','none');
            colormap(app.UIAxes2,'jet');
            c_amp = colorbar(app.UIAxes2);
            c_amp.Label.String = 'Campo Eletrico [dBm]';
            set(findall(gcf,'-property','FontSize'),'FontSize',14);
            set(gcf,'units','centimeters','position',[5,5,app.X/3,app.Y/3]);
     
            buf_aux = sprintf("OBJETO DIGITALIZADO - DISTRIBUICAO CAMPOS");
            title(app.UIAxes2,buf_aux);
            ylabel(app.UIAxes2,'Largura [mm]')
            xlabel(app.UIAxes2,'Comprimento [mm]')


            drawnow;
     
            hold on;

        end

        % Value changing function: FrequenciaSpinner
        function FrequenciaSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.cursor_freq = app.freqs(changingValue);
            app.EditField.Value = app.cursor_freq;
            if ~isempty(app.VerticalLine)
                delete(app.VerticalLine);
            end
            app.VerticalLine = line(app.UIAxes, [app.cursor_freq app.cursor_freq], ylim(app.UIAxes), 'Color', [0, 0, 1, 0.5], 'LineWidth', 2);
        end

        % Button pushed function: BuscarSensorButton
        function BuscarSensorButtonButtonPushed(app, event)
            close all;  % Fecha qualquer janela aberta
            app.BuscarSensorButton.Enable = "Off";  % Desabilita o botão enquanto busca
            app.STATUSTextArea.Value = "Procurando Sensor...";  % Atualiza o status
        
            % Lista as portas disponíveis no computador
            portas = serialportlist("available");  
            sensor_nao_localizado = 1;  % Flag para verificar se o sensor foi encontrado
        
            for i = 1:length(portas)  % Percorre todas as portas disponíveis
                aux1 = portas(i);  % Porta atual
        
                % Cria o objeto serial para a porta
                try
                    app.sensor = serialport(aux1, 9600);  % Configura a comunicação
                    app.sensor.Timeout = 0.1;  % Define o timeout de leitura
                    configureTerminator(app.sensor, "CR/LF");  % Configura terminadores CR e LF
        
                    % Lê os dados do sensor
                    data = readline(app.sensor);  % Lê uma linha de dados
        
                    if isa(data, 'string') && contains(data, "mm")  % Verifica se a resposta é válida
                        sensor_nao_localizado = 0;  % Sensor encontrado
        
                        % Extrai o número da porta COM
                        porta = str2double(extractBetween(aux1, 4, 5)); 
  
        
                        % Atualiza os campos da interface
                        app.PortaCOMSensorEditField.Value = porta;
                        app.PortaCOMSensorEditField.Editable = "Off";  % Torna o campo não editável
                        app.BuscarSensorButton.Enable = 'Off';  % Desabilita o botão de busca
        
                        app.STATUSTextArea.Value = "Sensor encontrado";  % Atualiza o status
                        break;  % Sai do loop quando o sensor for encontrado
                    end
                catch
                    % Caso haja algum erro ao tentar se comunicar com o sensor, ele ignora e passa para a próxima porta
                    continue;
                end
            end
        
            if sensor_nao_localizado
                app.STATUSTextArea.Value = "Sensor não encontrado";  % Caso não encontre o sensor
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Principal = uitab(app.TabGroup);
            app.Principal.Title = 'TELA INICIAL';
            app.BuscarTINYButton = uibutton(app.Principal, 'push');
            app.BuscarTINYButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarTINYButtonPushed, true);
            app.BuscarTINYButton.Text = 'Buscar TINY';
            app.BuscarSCANNERButton = uibutton(app.Principal, 'push');
            app.BuscarSCANNERButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarSCANNERButtonPushed, true);
            app.BuscarSCANNERButton.Enable = 'off';
            app.BuscarSCANNERButton.Text = 'Buscar SCANNER';
            app.PortaCOMdoTINYEditField = uieditfield(app.Principal, 'numeric');
            app.PortaCOMdoTINYEditField.ValueChangedFcn = createCallbackFcn(app, @PortaCOMdoTINYEditFieldValueChanged, true);
            app.PortaCOMdoSCANNEREditField = uieditfield(app.Principal, 'numeric');
            app.PortaCOMdoSCANNEREditField.ValueChangedFcn = createCallbackFcn(app, @PortaCOMdoSCANNEREditFieldValueChanged, true);
            app.PortaCOMdoSCANNEREditField.Enable = 'off';
            app.MODODEOPERAODropDown = uidropdown(app.Principal);
            app.MODODEOPERAODropDown.Items = {'Selecione', 'Varredura Completa', 'Frequências Específicas'};
            app.MODODEOPERAODropDown.ValueChangedFcn = createCallbackFcn(app, @MODODEOPERAODropDownValueChanged, true);
            app.MODODEOPERAODropDown.Enable = 'off';
            app.MODODEOPERAODropDown.Value = 'Selecione';
            app.FreqInicialEditField = uieditfield(app.Principal, 'text');
            app.FreqInicialEditField.ValueChangedFcn = createCallbackFcn(app, @FreqInicialEditFieldValueChanged, true);
            app.FreqInicialEditField.Visible = 'off';
            app.DropDownFreqInicial = uidropdown(app.Principal);
            app.DropDownFreqInicial.Items = {'GHz', 'MHz', 'kHz', 'Hz'};
            app.DropDownFreqInicial.ValueChangedFcn = createCallbackFcn(app, @DropDownFreqInicialValueChanged, true);
            app.DropDownFreqInicial.Visible = 'off';
            app.DropDownFreqInicial.Value = 'Hz';
            app.FreqFinalEditField = uieditfield(app.Principal, 'text');
            app.FreqFinalEditField.ValueChangedFcn = createCallbackFcn(app, @FreqFinalEditFieldValueChanged, true);
            app.FreqFinalEditField.Visible = 'off';
            app.DropDownFreqFinal = uidropdown(app.Principal);
            app.DropDownFreqFinal.Items = {'GHz', 'MHz', 'kHz', 'Hz'};
            app.DropDownFreqFinal.ValueChangedFcn = createCallbackFcn(app, @DropDownFreqFinalValueChanged, true);
            app.DropDownFreqFinal.Visible = 'off';
            app.DropDownFreqFinal.Value = 'Hz';
            app.DropDownRBW = uidropdown(app.Principal);
            app.DropDownRBW.Items = {'0.2', '1', '3', '10', '30', '100', '300', '600', '850'};
            app.DropDownRBW.ValueChangedFcn = createCallbackFcn(app, @DropDownRBWValueChanged2, true);
            app.DropDownRBW.Visible = 'off';
            app.DropDownRBW.Value = '850';
            app.RBWkHzEditFieldLabel = uilabel(app.Principal);
            app.RBWkHzEditFieldLabel.Visible = 'off';
            app.RBWkHzEditFieldLabel.Text = 'RBW [kHz]';
            app.NumerodefrequenciasSpinner = uispinner(app.Principal);
            app.NumerodefrequenciasSpinner.Limits = [0 4];
            app.NumerodefrequenciasSpinner.ValueChangedFcn = createCallbackFcn(app, @NumerodefrequenciasSpinnerValueChanged2, true);
            app.NumerodefrequenciasSpinner.Visible = 'off';
            app.Freq1MHzEditField = uieditfield(app.Principal, 'numeric');
            app.Freq1MHzEditField.ValueChangedFcn = createCallbackFcn(app, @Freq1MHzEditFieldValueChanged, true);
            app.Freq1MHzEditField.Editable = 'off';
            app.Freq1MHzEditField.Visible = 'off';
            app.DropDownFreq1 = uidropdown(app.Principal);
            app.DropDownFreq1.Items = {'GHz', 'MHz', 'kHz', 'Hz'};
            app.DropDownFreq1.ValueChangedFcn = createCallbackFcn(app, @DropDownFreq1ValueChanged, true);
            app.DropDownFreq1.Visible = 'off';
            app.DropDownFreq1.Value = 'Hz';
            app.Freq2MHzEditField = uieditfield(app.Principal, 'numeric');
            app.Freq2MHzEditField.ValueChangedFcn = createCallbackFcn(app, @DropDownFreq2ValueChanged, true);
            app.Freq2MHzEditField.Editable = 'off';
            app.Freq2MHzEditField.Visible = 'off';
            app.DropDownFreq2 = uidropdown(app.Principal);
            app.DropDownFreq2.Items = {'GHz', 'MHz', 'kHz', 'Hz'};
            app.DropDownFreq2.ValueChangedFcn = createCallbackFcn(app, @DropDownFreq2ValueChanged, true);
            app.DropDownFreq2.Visible = 'off';
            app.DropDownFreq2.Value = 'Hz';
            app.Freq3MHzEditField = uieditfield(app.Principal, 'numeric');
            app.Freq3MHzEditField.ValueChangedFcn = createCallbackFcn(app, @Freq3MHzEditFieldValueChanged, true);
            app.Freq3MHzEditField.Editable = 'off';
            app.Freq3MHzEditField.Visible = 'off';
            app.DropDownFreq3 = uidropdown(app.Principal);
            app.DropDownFreq3.Items = {'GHz', 'MHz', 'kHz', 'Hz'};
            app.DropDownFreq3.ValueChangedFcn = createCallbackFcn(app, @DropDownFreq3ValueChanged, true);
            app.DropDownFreq3.Visible = 'off';
            app.DropDownFreq3.Value = 'Hz';
            app.Freq4MHzEditField = uieditfield(app.Principal, 'numeric');
            app.Freq4MHzEditField.ValueChangedFcn = createCallbackFcn(app, @Freq4MHzEditFieldValueChanged, true);
            app.Freq4MHzEditField.Editable = 'off';
            app.Freq4MHzEditField.Visible = 'off';
            app.DropDownFreq4 = uidropdown(app.Principal);
            app.DropDownFreq4.Items = {'GHz', 'MHz', 'kHz', 'Hz'};
            app.DropDownFreq4.ValueChangedFcn = createCallbackFcn(app, @DropDownFreq4ValueChanged, true);
            app.DropDownFreq4.Visible = 'off';
            app.DropDownFreq4.Value = 'Hz';
            app.TempoEstimadoEditField = uieditfield(app.Principal, 'text');
            app.TempoEstimadoEditField.Editable = 'off';
            app.TempoEstimadoEditField.Visible = 'off';
            app.ComprimentoXmmEditField = uieditfield(app.Principal, 'numeric');
            app.ComprimentoXmmEditField.Limits = [0 240];
            app.ComprimentoXmmEditField.ValueChangedFcn = createCallbackFcn(app, @ComprimentoXmmEditFieldValueChanged, true);
            app.ComprimentoXmmEditField.Enable = 'off';
            app.LarguraYmmEditField = uieditfield(app.Principal, 'numeric');
            app.LarguraYmmEditField.Limits = [0 220];
            app.LarguraYmmEditField.ValueChangedFcn = createCallbackFcn(app, @LarguraYmmEditFieldValueChanged, true);
            app.LarguraYmmEditField.Enable = 'off';
            app.AlturadomaiorcomponentemmEditField_2 = uieditfield(app.Principal, 'numeric');
            app.AlturadomaiorcomponentemmEditField_2.Limits = [0 100];
            app.AlturadomaiorcomponentemmEditField_2.ValueChangedFcn = createCallbackFcn(app, @AlturadomaiorcomponentemmEditField_2ValueChanged, true);
            app.AlturadomaiorcomponentemmEditField_2.Enable = 'off';
            app.ResoluoPassommEditField = uieditfield(app.Principal, 'numeric');
            app.ResoluoPassommEditField.Limits = [0 220];
            app.ResoluoPassommEditField.ValueChangedFcn = createCallbackFcn(app, @ResoluoPassommEditFieldValueChanged, true);
            app.ResoluoPassommEditField.Enable = 'off';
            app.DistnciaasermantidadaplacammEditField = uieditfield(app.Principal, 'numeric');
            app.DistnciaasermantidadaplacammEditField.ValueChangedFcn = createCallbackFcn(app, @DistnciaasermantidadaplacammEditFieldValueChanged, true);
            app.DistnciaasermantidadaplacammEditField.Enable = 'off';
            app.NomedoArquivoEditField = uieditfield(app.Principal, 'text');
            app.NomedoArquivoEditField.ValueChangedFcn = createCallbackFcn(app, @NomedoArquivoEditFieldValueChanged, true);
            app.IniciarButton = uibutton(app.Principal, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Enable = 'off';
            app.IniciarButton.Text = 'Iniciar';
            app.medecampoButton = uibutton(app.Principal, 'push');
            app.medecampoButton.ButtonPushedFcn = createCallbackFcn(app, @medecampoButtonPushed, true);
            app.medecampoButton.Text = 'mede campo';
            app.medealturaButton = uibutton(app.Principal, 'push');
            app.medealturaButton.ButtonPushedFcn = createCallbackFcn(app, @medealturaButtonPushed, true);
            app.medealturaButton.Text = 'mede altura';
            app.STATUSTextArea = uitextarea(app.Principal);
            app.moverButton = uibutton(app.Principal, 'push');
            app.moverButton.ButtonPushedFcn = createCallbackFcn(app, @moverButtonPushed, true);
            app.moverButton.Text = 'mover';
            app.ParmetrosdaMedioLabel = uilabel(app.Principal);
            app.ParmetrosdaMedioLabel.Text = 'Parâmetros da Medição';
            app.DistnciaasermantidadaplacammEditFieldLabel = uilabel(app.Principal);
            app.DistnciaasermantidadaplacammEditFieldLabel.Enable = 'off';
            app.DistnciaasermantidadaplacammEditFieldLabel.Text = 'Distância a ser mantida da placa [mm]';
            app.PortaCOMdoSCANNEREditFieldLabel = uilabel(app.Principal);
            app.PortaCOMdoSCANNEREditFieldLabel.Enable = 'off';
            app.PortaCOMdoSCANNEREditFieldLabel.Text = 'Porta COM do SCANNER';
            app.STATUSTextAreaLabel = uilabel(app.Principal);
            app.STATUSTextAreaLabel.Text = 'STATUS';
            app.NomedoArquivoEditFieldLabel = uilabel(app.Principal);
            app.NomedoArquivoEditFieldLabel.Text = 'Nome do Arquivo';
            app.ResoluoPassommEditFieldLabel = uilabel(app.Principal);
            app.ResoluoPassommEditFieldLabel.Enable = 'off';
            app.ResoluoPassommEditFieldLabel.Text = 'Resolução - Passo [mm] ';
            app.AlturadomaiorcomponentemmEditField_2Label = uilabel(app.Principal);
            app.AlturadomaiorcomponentemmEditField_2Label.Enable = 'off';
            app.AlturadomaiorcomponentemmEditField_2Label.Text = 'Altura do maior componente [mm]: ';
            app.LarguraYmmEditFieldLabel = uilabel(app.Principal);
            app.LarguraYmmEditFieldLabel.Enable = 'off';
            app.LarguraYmmEditFieldLabel.Text = 'Largura Y [mm]:';
            app.ComprimentoXmmEditFieldLabel = uilabel(app.Principal);
            app.ComprimentoXmmEditFieldLabel.Enable = 'off';
            app.ComprimentoXmmEditFieldLabel.Text = 'Comprimento X [mm]:';
            app.Freq2MHzEditFieldLabel = uilabel(app.Principal);
            app.Freq2MHzEditFieldLabel.Visible = 'off';
            app.Freq2MHzEditFieldLabel.Text = 'Freq2 [MHz]';
            app.Freq3MHzEditFieldLabel = uilabel(app.Principal);
            app.Freq3MHzEditFieldLabel.Visible = 'off';
            app.Freq3MHzEditFieldLabel.Text = 'Freq3 [MHz]';
            app.Freq4MHzEditFieldLabel = uilabel(app.Principal);
            app.Freq4MHzEditFieldLabel.Visible = 'off';
            app.Freq4MHzEditFieldLabel.Text = 'Freq4 [MHz]';
            app.Freq1MHzEditFieldLabel = uilabel(app.Principal);
            app.Freq1MHzEditFieldLabel.Visible = 'off';
            app.Freq1MHzEditFieldLabel.Text = 'Freq1 [MHz]';
            app.TempoEstimadoEditFieldLabel = uilabel(app.Principal);
            app.TempoEstimadoEditFieldLabel.Visible = 'off';
            app.TempoEstimadoEditFieldLabel.Text = 'Tempo Estimado';
            app.FreqInicialEditFieldLabel = uilabel(app.Principal);
            app.FreqInicialEditFieldLabel.Visible = 'off';
            app.FreqInicialEditFieldLabel.Text = 'Freq Inicial';
            app.NumerodefrequenciasSpinnerLabel = uilabel(app.Principal);
            app.NumerodefrequenciasSpinnerLabel.Visible = 'off';
            app.NumerodefrequenciasSpinnerLabel.Text = 'Numero de frequencias';
            app.FreqFinalEditFieldLabel = uilabel(app.Principal);
            app.FreqFinalEditFieldLabel.Visible = 'off';
            app.FreqFinalEditFieldLabel.Text = 'Freq Final';
            app.MODODEOPERAODropDownLabel = uilabel(app.Principal);
            app.MODODEOPERAODropDownLabel.Enable = 'off';
            app.MODODEOPERAODropDownLabel.Text = 'MODO DE OPERAÇÃO';
            app.PortaCOMdoTINYEditFieldLabel = uilabel(app.Principal);
            app.PortaCOMdoTINYEditFieldLabel.Text = 'Porta COM do TINY';
            app.ParmetrosdaPlacaLabel = uilabel(app.Principal);
            app.ParmetrosdaPlacaLabel.Text = 'Parâmetros da Placa';
            app.UsarescalaautomaticaSwitchLabel = uilabel(app.Principal);
            app.UsarescalaautomaticaSwitchLabel.Text = 'Usar escala automatica';
            app.UsarescalaautomaticaSwitch = uiswitch(app.Principal, 'slider');
            app.UsarescalaautomaticaSwitch.ValueChangedFcn = createCallbackFcn(app, @UsarescalaautomaticaSwitchValueChanged, true);
            app.UsarescalaautomaticaSwitch.Value = 'On';
            app.MostarmapadealturasSwitchLabel = uilabel(app.Principal);
            app.MostarmapadealturasSwitchLabel.Text = 'Mostar mapa de alturas';
            app.MostarmapadealturasSwitch = uiswitch(app.Principal, 'slider');
            app.MostarmapadealturasSwitch.ValueChangedFcn = createCallbackFcn(app, @MostarmapadealturasSwitchValueChanged, true);
            app.MostarmapadealturasSwitch.Value = 'On';
            app.TamanhodasondaEditFieldLabel = uilabel(app.Principal);
            app.TamanhodasondaEditFieldLabel.Text = 'Tamanho da sonda ';
            app.TamanhodasondaEditField = uieditfield(app.Principal, 'numeric');
            app.TamanhodasondaEditField.Limits = [0 40];
            app.TamanhodasondaEditField.ValueChangedFcn = createCallbackFcn(app, @TamanhodasondaEditFieldValueChanged, true);
            app.TamanhodasondaEditField.Value = 40;
            app.DistanciaentresensordealturaesondaemXmmEditFieldLabel = uilabel(app.Principal);
            app.DistanciaentresensordealturaesondaemXmmEditFieldLabel.Text = 'Distancia entre sensor de altura e sonda em X [mm] ';
            app.DistanciaentresensordealturaesondaemXmmEditField = uieditfield(app.Principal, 'numeric');
            app.DistanciaentresensordealturaesondaemXmmEditField.ValueChangedFcn = createCallbackFcn(app, @DistanciaentresensordealturaesondaemXmmEditFieldValueChanged, true);
            app.DistanciaentresensordealturaesondaemXmmEditField.Value = 35;
            app.DistanciaentresensordealturaesondaemYmmEditFieldLabel = uilabel(app.Principal);
            app.DistanciaentresensordealturaesondaemYmmEditFieldLabel.Text = 'Distancia entre sensor de altura e sonda em Y [mm]';
            app.DistanciaentresensordealturaesondaemYmmEditField = uieditfield(app.Principal, 'numeric');
            app.DistanciaentresensordealturaesondaemYmmEditField.Limits = [0 110];
            app.DistanciaentresensordealturaesondaemYmmEditField.ValueChangedFcn = createCallbackFcn(app, @DistanciaentresensordealturaesondaemYmmEditFieldValueChanged, true);
            app.DistanciaentresensordealturaesondaemYmmEditField.Value = 55;
            app.BuscarSensorButton = uibutton(app.Principal, 'push');
            app.BuscarSensorButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarSensorButtonButtonPushed, true);
            app.BuscarSensorButton.Enable = 'off';
            app.BuscarSensorButton.Text = 'Buscar Sensor';
            app.PortaCOMSensorEditField = uieditfield(app.Principal, 'numeric');
            app.PortaCOMSensorEditField.Enable = 'off';
            app.PortaCOMSensorEditFieldLabel = uilabel(app.Principal);
            app.PortaCOMSensorEditFieldLabel.Text = 'Porta COM do Sensor';
            app.CAMPOSTab = uitab(app.TabGroup);
            app.CAMPOSTab.Title = 'CAMPOS';
            app.UIAxes = uiaxes(app.CAMPOSTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Frequencias')
            ylabel(app.UIAxes, 'Amplitude [dBm]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.CAMPOSTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.EditField = uieditfield(app.CAMPOSTab, 'numeric');
            app.FrequenciaSpinner = uispinner(app.CAMPOSTab);
            app.FrequenciaSpinner.ValueChangingFcn = createCallbackFcn(app, @FrequenciaSpinnerValueChanging, true);
            app.FrequenciaSpinner.Limits = [1 290];
            app.FrequenciaSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequenciaSpinnerValueChanged, true);
            app.FrequenciaSpinner.Value = 1;
            app.FrequenciaSpinnerLabel = uilabel(app.CAMPOSTab);
            app.FrequenciaSpinnerLabel.Text = 'Frequencia';
            app.MXIMOTab = uitab(app.TabGroup);
            app.MXIMOTab.Title = 'MÁXIMO';
            app.UIAxesFreq = uiaxes(app.MXIMOTab);
            title(app.UIAxesFreq, 'Title')
            xlabel(app.UIAxesFreq, 'X')
            ylabel(app.UIAxesFreq, 'Y')
            zlabel(app.UIAxesFreq, 'Z')
            app.UIAxesCampo = uiaxes(app.MXIMOTab);
            title(app.UIAxesCampo, 'Title')
            xlabel(app.UIAxesCampo, 'X')
            ylabel(app.UIAxesCampo, 'Y')
            zlabel(app.UIAxesCampo, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end